classdef dual_radar_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainTextArea                    matlab.ui.control.TextArea
        ConfigurationPanel              matlab.ui.container.Panel
        ScannerConfigurationLamp        matlab.ui.control.Lamp
        ScannerConfigurationLabel       matlab.ui.control.Label
        Radar1ConfigurationLabel        matlab.ui.control.Label
        Radar2ConfigurationLabel        matlab.ui.control.Label
        MCUConfigurationLabel           matlab.ui.control.Label
        Radar1ConfigurationLamp         matlab.ui.control.Lamp
        Radar2ConfigurationLamp         matlab.ui.control.Lamp
        MCUConfigurationLamp            matlab.ui.control.Lamp
        ConnectionsPanel                matlab.ui.container.Panel
        ScannerConnectionLamp           matlab.ui.control.Lamp
        ScannerConnectionLabel          matlab.ui.control.Label
        Radar1ConnectionLabel           matlab.ui.control.Label
        Radar2ConnectionLabel           matlab.ui.control.Label
        MCUConnectionLabel              matlab.ui.control.Label
        Radar1ConnectionLamp            matlab.ui.control.Lamp
        Radar2ConnectionLamp            matlab.ui.control.Lamp
        MCUConnectionLamp               matlab.ui.control.Lamp
        AuthorJosiahWSmithAdvisorMuratTorlakLabel  matlab.ui.control.Label
        DualRadarSARControllerLabel     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RadarSetupTab                   matlab.ui.container.Tab
        DCA2ConfigurationPanel          matlab.ui.container.Panel
        DCA2PrepareLamp                 matlab.ui.control.Lamp
        PrepareDCA2Button               matlab.ui.control.Button
        DataPortEditField_2             matlab.ui.control.EditField
        DataPortEditField_2Label        matlab.ui.control.Label
        ConfigPortEditField_2           matlab.ui.control.EditField
        ConfigPortEditField_2Label      matlab.ui.control.Label
        DCAIPAddressEditField_2         matlab.ui.control.EditField
        DCAIPAddressEditField_2Label    matlab.ui.control.Label
        SystemIPAddressEditField_2      matlab.ui.control.EditField
        SystemIPAddressEditField_2Label  matlab.ui.control.Label
        DCA1ConfigurationPanel          matlab.ui.container.Panel
        DCA1PrepareLamp                 matlab.ui.control.Lamp
        PrepareDCA1Button               matlab.ui.control.Button
        DataPortEditField               matlab.ui.control.EditField
        DataPortEditFieldLabel          matlab.ui.control.Label
        ConfigPortEditField             matlab.ui.control.EditField
        ConfigPortEditFieldLabel        matlab.ui.control.Label
        DCAIPAddressEditField           matlab.ui.control.EditField
        DCAIPAddressEditFieldLabel      matlab.ui.control.Label
        SystemIPAddressEditField        matlab.ui.control.EditField
        SystemIPAddressEditFieldLabel   matlab.ui.control.Label
        Radar277GHzConfigurationPanel   matlab.ui.container.Panel
        StopButton_2                    matlab.ui.control.Button
        StartButton_2                   matlab.ui.control.Button
        Tx1CheckBox_2                   matlab.ui.control.CheckBox
        Tx0CheckBox_2                   matlab.ui.control.CheckBox
        Rx2CheckBox_2                   matlab.ui.control.CheckBox
        Rx3CheckBox_2                   matlab.ui.control.CheckBox
        Rx1CheckBox_2                   matlab.ui.control.CheckBox
        Rx0CheckBox_2                   matlab.ui.control.CheckBox
        ConfigureRadar2Button           matlab.ui.control.Button
        PeriodicitymsEditField_2        matlab.ui.control.NumericEditField
        PeriodicitymsEditField_2Label   matlab.ui.control.Label
        NoofChirpLoopsEditField_2       matlab.ui.control.NumericEditField
        NoofChirpLoopsEditField_2Label  matlab.ui.control.Label
        NoofFramesEditField_2           matlab.ui.control.NumericEditField
        NoofFramesEditField_2Label      matlab.ui.control.Label
        RampEndTimeusEditField_2        matlab.ui.control.NumericEditField
        RampEndTimeusEditField_2Label   matlab.ui.control.Label
        SampleRatekspsEditField_2       matlab.ui.control.NumericEditField
        SampleRatekspsEditField_2Label  matlab.ui.control.Label
        ADCSamplesEditField_2           matlab.ui.control.NumericEditField
        ADCSamplesEditField_2Label      matlab.ui.control.Label
        ADCStartTimeusEditField_2       matlab.ui.control.NumericEditField
        ADCStartTimeusEditField_2Label  matlab.ui.control.Label
        TXStartTimeusEditField_2        matlab.ui.control.NumericEditField
        TXStartTimeusEditField_2Label   matlab.ui.control.Label
        IdleTimeusEditField_2           matlab.ui.control.NumericEditField
        IdleTimeusEditField_2Label      matlab.ui.control.Label
        FreqSlopeMHzusEditField_2       matlab.ui.control.NumericEditField
        FreqSlopeMHzusEditField_2Label  matlab.ui.control.Label
        StartFreqGHzEditField_2         matlab.ui.control.NumericEditField
        StartFreqGHzEditField_2Label    matlab.ui.control.Label
        SerialConnectionsPanel          matlab.ui.container.Panel
        ConnectRadar2Button             matlab.ui.control.Button
        ConnectRadar1Button             matlab.ui.control.Button
        Radar160GHzConfigurationPanel   matlab.ui.container.Panel
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        Tx2CheckBox                     matlab.ui.control.CheckBox
        Tx1CheckBox                     matlab.ui.control.CheckBox
        Tx0CheckBox                     matlab.ui.control.CheckBox
        Rx2CheckBox                     matlab.ui.control.CheckBox
        Rx3CheckBox                     matlab.ui.control.CheckBox
        Rx1CheckBox                     matlab.ui.control.CheckBox
        Rx0CheckBox                     matlab.ui.control.CheckBox
        ConfigureRadar1Button           matlab.ui.control.Button
        PeriodicitymsEditField          matlab.ui.control.NumericEditField
        PeriodicitymsEditFieldLabel     matlab.ui.control.Label
        NoofChirpLoopsEditField         matlab.ui.control.NumericEditField
        NoofChirpLoopsEditFieldLabel    matlab.ui.control.Label
        NoofFramesEditField             matlab.ui.control.NumericEditField
        NoofFramesEditFieldLabel        matlab.ui.control.Label
        RampEndTimeusEditField          matlab.ui.control.NumericEditField
        RampEndTimeusEditFieldLabel     matlab.ui.control.Label
        SampleRatekspsEditField         matlab.ui.control.NumericEditField
        SampleRatekspsEditFieldLabel    matlab.ui.control.Label
        ADCSamplesEditField             matlab.ui.control.NumericEditField
        ADCSamplesEditFieldLabel        matlab.ui.control.Label
        ADCStartTimeusEditField         matlab.ui.control.NumericEditField
        ADCStartTimeusEditFieldLabel    matlab.ui.control.Label
        TXStartTimeusEditField          matlab.ui.control.NumericEditField
        TXStartTimeusEditFieldLabel     matlab.ui.control.Label
        IdleTimeusEditField             matlab.ui.control.NumericEditField
        IdleTimeusEditFieldLabel        matlab.ui.control.Label
        FreqSlopeMHzusEditField         matlab.ui.control.NumericEditField
        FreqSlopeMHzusEditFieldLabel    matlab.ui.control.Label
        StartFreqGHzEditField           matlab.ui.control.NumericEditField
        StartFreqGHzEditFieldLabel      matlab.ui.control.Label
        MCUSetupTab                     matlab.ui.container.Tab
        SerialConnectionsPanel_2        matlab.ui.container.Panel
        ConnectMCUButton                matlab.ui.control.Button
        ScannerSetupTab                 matlab.ui.container.Tab
        SerialConnectionsPanel_3        matlab.ui.container.Panel
        ConnectScannerButton            matlab.ui.control.Button
    end

    properties
        mmWavePath = "C:\ti\mmwave_studio_02_01_01_00\mmWaveStudio"
        
        dca1
        dca2
        radar1
        radar2
        scanner
        mcu
    end
    
    methods (Access = private)
        
        function setupRadars(app)
            app.radar1.connectionLamp = app.Radar1ConnectionLamp;
            app.radar1.configurationLamp = app.Radar1ConfigurationLamp;
            
            app.radar2.connectionLamp = app.Radar2ConnectionLamp;
            app.radar2.configurationLamp = app.Radar2ConfigurationLamp;
        end
        
        function setupMCU(app)
            app.mcu.connectionLamp = app.MCUConnectionLamp;
            app.mcu.configurationLamp = app.MCUConfigurationLamp;
        end
        
        function setupDCAs(app)
            app.dca1.prepareLamp = app.DCA1PrepareLamp;
            app.dca2.prepareLamp = app.DCA2PrepareLamp;
        end
        
        function setupScanner(app)
            app.scanner.connectionLamp = app.ScannerConnectionLamp;
            app.scanner.configurationLamp = app.ScannerConfigurationLamp;
        end
        
        function NumChirpLoops = checkRadarParameters(app,radarObj,NumChirpLoops,ADCSamples)
            % Ensure number of chirp loops is multiple of 4
            if mod(NumChirpLoops,4) ~= 0
                % Reduce to value that is multiple of 4
                NumChirpLoops = NumChirpLoops - mod(NumChirpLoops,4);
            end
            
            % Ensure memory usage is OK
            if ADCSamples * NumChirpLoops > 16384
                app.MainTextArea.Value = "Out of limits on chirp parameters!";
                radarObj.configurationLamp.Color = 'red';

                cpN = 16384/ADCSamples;
                adcN = 16384/NumChirpLoops;
                app.MainTextArea.Value = "To resolve reduce to " + floor(cpN) + " chirp loops " + " - Reduce to " + floor(adcN) + " ADC samples";
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add the folders to the path
            splitPath = regexp(cd,filesep,'split');
            if string(splitPath{end}) ~= "dual-radar-gui"
                try
                    cd(fileparts(which('dual_radar_gui.mlapp')));
                catch
                end
            end
            splitPath = regexp(cd,filesep,'split');
            while string(splitPath{end}) ~= "dual-radar-gui"
                selection = uiconfirm(app.UIFigure,'Would you liked to choose the correct "dual-radar-gui" folder?','Incorrect opening folder',...
                    'Icon','warning');
                if string(selection) == "Cancel"
                    warning("Cound not find correct folder!");
                    UIFigureCloseRequest(app, 0);
                    return;
                end

                drawnow
                figure(app.UIFigure)
                directoryname = uigetdir("","Select ""dual-radar-gui"" Path");
                cd(directoryname);
                splitPath = regexp(cd,filesep,'split');
            end
            addpath(genpath("./"));

            % Set the mmWaveStudio path
            splitPath = regexp(app.mmWavePath,filesep,'split');
            while string(splitPath{end}) ~= "mmWaveStudio"
                app.mmWavePath = uigetdir('C:\',"Select mmWaveStudio Installation Path");
                splitPath = regexp(app.mmWavePath,filesep,'split');
            end
            app.MainTextArea.Value = "mmWaveStudio path properly set to " + app.mmWavePath;
            
            app.dca1 = DCA_Device(app,1);
            app.dca2 = DCA_Device(app,2);
            setupDCAs(app);
            
            app.radar1 = TI_Radar_Device(app,1);
            app.radar2 = TI_Radar_Device(app,2);
            setupRadars(app);
            
            app.scanner = SAR_Scanner_Device(app);
            setupScanner(app);
            
            app.mcu = MCU_Device(app);
            setupMCU(app);
        end

        % Button pushed function: ConnectRadar1Button
        function ConnectRadar1ButtonPushed(app, event)
            app.radar1.SerialConnect(app);
        end

        % Button pushed function: ConnectRadar2Button
        function ConnectRadar2ButtonPushed(app, event)
            app.radar2.SerialConnect(app);
        end

        % Button pushed function: ConfigureRadar1Button
        function ConfigureRadar1ButtonPushed(app, event)
            % Check radar parameters
            app.NoofChirpLoopsEditField.Value = checkRadarParameters(app,app.radar1,app.NoofChirpLoopsEditField.Value,app.ADCSamplesEditField.Value);
            
            % Set radar1 properties
            app.radar1.f0_GHz = app.StartFreqGHzEditField.Value;
            app.radar1.K = app.FreqSlopeMHzusEditField.Value;
            app.radar1.idleTime_us = app.IdleTimeusEditField.Value;
            app.radar1.txStartTime_us = app.TXStartTimeusEditField.Value;
            app.radar1.adcStartTime_us = app.ADCStartTimeusEditField.Value;
            app.radar1.adcSamples = app.ADCSamplesEditField.Value;
            app.radar1.fS_ksps = app.SampleRatekspsEditField.Value;
            app.radar1.rampEndTime_us = app.RampEndTimeusEditField.Value;
            app.radar1.numFrames = app.NoofFramesEditField.Value;
            app.radar1.numChirps = app.NoofChirpLoopsEditField.Value;
            app.radar1.pri_ms = app.PeriodicitymsEditField.Value;
            app.radar1.nRx = 4;
            app.radar1.nTx = app.Tx0CheckBox.Value + app.Tx1CheckBox.Value + app.Tx2CheckBox.Value;
            
            app.radar1.Configure();
        end

        % Button pushed function: ConfigureRadar2Button
        function ConfigureRadar2ButtonPushed(app, event)
            % Check radar parameters
            app.NoofChirpLoopsEditField_2.Value = checkRadarParameters(app,app.radar2,app.NoofChirpLoopsEditField_2.Value,app.ADCSamplesEditField_2.Value);
            
            % Set radar2 properties
            app.radar2.f0_GHz = app.StartFreqGHzEditField_2.Value;
            app.radar2.K = app.FreqSlopeMHzusEditField_2.Value;
            app.radar2.idleTime_us = app.IdleTimeusEditField_2.Value;
            app.radar2.txStartTime_us = app.TXStartTimeusEditField_2.Value;
            app.radar2.adcStartTime_us = app.ADCStartTimeusEditField_2.Value;
            app.radar2.adcSamples = app.ADCSamplesEditField_2.Value;
            app.radar2.fS_ksps = app.SampleRatekspsEditField_2.Value;
            app.radar2.rampEndTime_us = app.RampEndTimeusEditField_2.Value;
            app.radar2.numFrames = app.NoofFramesEditField_2.Value;
            app.radar2.numChirps = app.NoofChirpLoopsEditField_2.Value;
            app.radar2.pri_ms = app.PeriodicitymsEditField_2.Value;
            app.radar2.nRx = 4;
            app.radar2.nTx = app.Tx0CheckBox_2.Value + app.Tx1CheckBox_2.Value;
            
            app.radar2.Configure();
        end

        % Button pushed function: PrepareDCA1Button
        function PrepareDCA1ButtonPushed(app, event)
            % Set dca1 properties
            app.dca1.systemIPAddress = app.SystemIPAddressEditField.Value;
            app.dca1.DCA1000IPAddress = app.DCAIPAddressEditField.Value;
            app.dca1.configPort = app.ConfigPortEditField.Value;
            app.dca1.dataPort = app.DataPortEditField.Value;
            
            app.dca1.Prepare();
        end

        % Button pushed function: PrepareDCA2Button
        function PrepareDCA2ButtonPushed(app, event)
            % Set dca2 properties
            app.dca2.systemIPAddress = app.SystemIPAddressEditField_2.Value;
            app.dca2.DCA1000IPAddress = app.DCAIPAddressEditField_2.Value;
            app.dca2.configPort = app.ConfigPortEditField_2.Value;
            app.dca2.dataPort = app.DataPortEditField_2.Value;
            
            app.dca2.Prepare();
        end

        % Value changed function: ADCSamplesEditField
        function ADCSamplesEditFieldValueChanged(app, event)
            % Make sure the ADC samples are the same
            app.ADCSamplesEditField_2.Value = app.ADCSamplesEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RadarSetupTab = uitab(app.TabGroup);
            app.RadarSetupTab.Title = 'Radar Setup';
            app.Radar160GHzConfigurationPanel = uipanel(app.RadarSetupTab);
            app.Radar160GHzConfigurationPanel.Title = '4. Radar 1 (60 GHz) Configuration';
            app.StartFreqGHzEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.StartFreqGHzEditFieldLabel.Text = 'Start Freq (GHz)';
            app.StartFreqGHzEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.StartFreqGHzEditField.ValueDisplayFormat = '%11.3f';
            app.StartFreqGHzEditField.Enable = 'off';
            app.StartFreqGHzEditField.Value = 60;
            app.FreqSlopeMHzusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.FreqSlopeMHzusEditFieldLabel.Text = 'Freq Slope (MHz/us)';
            app.FreqSlopeMHzusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.FreqSlopeMHzusEditField.ValueDisplayFormat = '%11.3f';
            app.FreqSlopeMHzusEditField.Value = 124.996;
            app.IdleTimeusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.IdleTimeusEditFieldLabel.Text = 'Idle Time (us)';
            app.IdleTimeusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.IdleTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.IdleTimeusEditField.Value = 10;
            app.TXStartTimeusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.TXStartTimeusEditFieldLabel.Text = 'TX Start Time (us)';
            app.TXStartTimeusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.TXStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.ADCStartTimeusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.ADCStartTimeusEditFieldLabel.Text = 'ADC Start Time (us)';
            app.ADCStartTimeusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.ADCStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.ADCSamplesEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.ADCSamplesEditFieldLabel.Text = 'ADC Samples';
            app.ADCSamplesEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.ADCSamplesEditField.Limits = [0 Inf];
            app.ADCSamplesEditField.RoundFractionalValues = 'on';
            app.ADCSamplesEditField.ValueDisplayFormat = '%11.3g';
            app.ADCSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @ADCSamplesEditFieldValueChanged, true);
            app.ADCSamplesEditField.Value = 64;
            app.SampleRatekspsEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.SampleRatekspsEditFieldLabel.Text = 'Sample Rate (ksps)';
            app.SampleRatekspsEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.SampleRatekspsEditField.Limits = [2000 37500];
            app.SampleRatekspsEditField.ValueDisplayFormat = '%11.3f';
            app.SampleRatekspsEditField.Value = 2000;
            app.RampEndTimeusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.RampEndTimeusEditFieldLabel.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.RampEndTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.RampEndTimeusEditField.Value = 32;
            app.NoofFramesEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.NoofFramesEditFieldLabel.Text = 'No of Frames';
            app.NoofFramesEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.NoofFramesEditField.ValueDisplayFormat = '%11.3g';
            app.NoofChirpLoopsEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.NoofChirpLoopsEditFieldLabel.Text = 'No of Chirp Loops';
            app.NoofChirpLoopsEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.NoofChirpLoopsEditField.ValueDisplayFormat = '%11.3g';
            app.NoofChirpLoopsEditField.Editable = 'off';
            app.NoofChirpLoopsEditField.Value = 4;
            app.PeriodicitymsEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.PeriodicitymsEditFieldLabel.Text = 'Periodicity (ms)';
            app.PeriodicitymsEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.PeriodicitymsEditField.ValueDisplayFormat = '%11.3f';
            app.PeriodicitymsEditField.Value = 50;
            app.ConfigureRadar1Button = uibutton(app.Radar160GHzConfigurationPanel, 'push');
            app.ConfigureRadar1Button.ButtonPushedFcn = createCallbackFcn(app, @ConfigureRadar1ButtonPushed, true);
            app.ConfigureRadar1Button.Text = 'Configure Radar 1';
            app.Rx0CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Rx0CheckBox.Enable = 'off';
            app.Rx0CheckBox.Text = 'Rx0';
            app.Rx0CheckBox.Value = true;
            app.Rx1CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Rx1CheckBox.Enable = 'off';
            app.Rx1CheckBox.Text = 'Rx1';
            app.Rx1CheckBox.Value = true;
            app.Rx3CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Rx3CheckBox.Enable = 'off';
            app.Rx3CheckBox.Text = 'Rx3';
            app.Rx3CheckBox.Value = true;
            app.Rx2CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Rx2CheckBox.Enable = 'off';
            app.Rx2CheckBox.Text = 'Rx2';
            app.Rx2CheckBox.Value = true;
            app.Tx0CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Tx0CheckBox.Enable = 'off';
            app.Tx0CheckBox.Text = 'Tx0';
            app.Tx0CheckBox.Value = true;
            app.Tx1CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Tx1CheckBox.Enable = 'off';
            app.Tx1CheckBox.Text = 'Tx1';
            app.Tx2CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Tx2CheckBox.Enable = 'off';
            app.Tx2CheckBox.Text = 'Tx2';
            app.Tx2CheckBox.Value = true;
            app.StartButton = uibutton(app.Radar160GHzConfigurationPanel, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Radar160GHzConfigurationPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.SerialConnectionsPanel = uipanel(app.RadarSetupTab);
            app.SerialConnectionsPanel.Title = '1. Serial Connection(s)';
            app.ConnectRadar1Button = uibutton(app.SerialConnectionsPanel, 'push');
            app.ConnectRadar1Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectRadar1ButtonPushed, true);
            app.ConnectRadar1Button.Text = 'Connect Radar 1';
            app.ConnectRadar2Button = uibutton(app.SerialConnectionsPanel, 'push');
            app.ConnectRadar2Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectRadar2ButtonPushed, true);
            app.ConnectRadar2Button.Text = 'Connect Radar 2';
            app.Radar277GHzConfigurationPanel = uipanel(app.RadarSetupTab);
            app.Radar277GHzConfigurationPanel.Title = '5. Radar 2 (77 GHz) Configuration';
            app.StartFreqGHzEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.StartFreqGHzEditField_2Label.Text = 'Start Freq (GHz)';
            app.StartFreqGHzEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.StartFreqGHzEditField_2.ValueDisplayFormat = '%11.3f';
            app.StartFreqGHzEditField_2.Editable = 'off';
            app.StartFreqGHzEditField_2.Value = 77;
            app.FreqSlopeMHzusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.FreqSlopeMHzusEditField_2Label.Text = 'Freq Slope (MHz/us)';
            app.FreqSlopeMHzusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.FreqSlopeMHzusEditField_2.ValueDisplayFormat = '%11.3f';
            app.FreqSlopeMHzusEditField_2.Value = 124.996;
            app.IdleTimeusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.IdleTimeusEditField_2Label.Text = 'Idle Time (us)';
            app.IdleTimeusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.IdleTimeusEditField_2.ValueDisplayFormat = '%11.3f';
            app.IdleTimeusEditField_2.Value = 10;
            app.TXStartTimeusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.TXStartTimeusEditField_2Label.Text = 'TX Start Time (us)';
            app.TXStartTimeusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.TXStartTimeusEditField_2.ValueDisplayFormat = '%11.3f';
            app.ADCStartTimeusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.ADCStartTimeusEditField_2Label.Text = 'ADC Start Time (us)';
            app.ADCStartTimeusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.ADCStartTimeusEditField_2.ValueDisplayFormat = '%11.3f';
            app.ADCSamplesEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.ADCSamplesEditField_2Label.Text = 'ADC Samples';
            app.ADCSamplesEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.ADCSamplesEditField_2.Limits = [0 Inf];
            app.ADCSamplesEditField_2.RoundFractionalValues = 'on';
            app.ADCSamplesEditField_2.ValueDisplayFormat = '%11.3g';
            app.ADCSamplesEditField_2.Editable = 'off';
            app.ADCSamplesEditField_2.Value = 64;
            app.SampleRatekspsEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.SampleRatekspsEditField_2Label.Text = 'Sample Rate (ksps)';
            app.SampleRatekspsEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.SampleRatekspsEditField_2.Limits = [2000 37500];
            app.SampleRatekspsEditField_2.ValueDisplayFormat = '%11.3f';
            app.SampleRatekspsEditField_2.Value = 2000;
            app.RampEndTimeusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.RampEndTimeusEditField_2Label.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.RampEndTimeusEditField_2.ValueDisplayFormat = '%11.3f';
            app.RampEndTimeusEditField_2.Value = 32;
            app.NoofFramesEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.NoofFramesEditField_2Label.Text = 'No of Frames';
            app.NoofFramesEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.NoofFramesEditField_2.ValueDisplayFormat = '%11.3g';
            app.NoofChirpLoopsEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.NoofChirpLoopsEditField_2Label.Text = 'No of Chirp Loops';
            app.NoofChirpLoopsEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.NoofChirpLoopsEditField_2.ValueDisplayFormat = '%11.3g';
            app.NoofChirpLoopsEditField_2.Editable = 'off';
            app.NoofChirpLoopsEditField_2.Value = 4;
            app.PeriodicitymsEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.PeriodicitymsEditField_2Label.Text = 'Periodicity (ms)';
            app.PeriodicitymsEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.PeriodicitymsEditField_2.ValueDisplayFormat = '%11.3f';
            app.PeriodicitymsEditField_2.Value = 50;
            app.ConfigureRadar2Button = uibutton(app.Radar277GHzConfigurationPanel, 'push');
            app.ConfigureRadar2Button.ButtonPushedFcn = createCallbackFcn(app, @ConfigureRadar2ButtonPushed, true);
            app.ConfigureRadar2Button.Text = 'Configure Radar 2';
            app.Rx0CheckBox_2 = uicheckbox(app.Radar277GHzConfigurationPanel);
            app.Rx0CheckBox_2.Enable = 'off';
            app.Rx0CheckBox_2.Text = 'Rx0';
            app.Rx0CheckBox_2.Value = true;
            app.Rx1CheckBox_2 = uicheckbox(app.Radar277GHzConfigurationPanel);
            app.Rx1CheckBox_2.Enable = 'off';
            app.Rx1CheckBox_2.Text = 'Rx1';
            app.Rx1CheckBox_2.Value = true;
            app.Rx3CheckBox_2 = uicheckbox(app.Radar277GHzConfigurationPanel);
            app.Rx3CheckBox_2.Enable = 'off';
            app.Rx3CheckBox_2.Text = 'Rx3';
            app.Rx3CheckBox_2.Value = true;
            app.Rx2CheckBox_2 = uicheckbox(app.Radar277GHzConfigurationPanel);
            app.Rx2CheckBox_2.Enable = 'off';
            app.Rx2CheckBox_2.Text = 'Rx2';
            app.Rx2CheckBox_2.Value = true;
            app.Tx0CheckBox_2 = uicheckbox(app.Radar277GHzConfigurationPanel);
            app.Tx0CheckBox_2.Enable = 'off';
            app.Tx0CheckBox_2.Text = 'Tx0';
            app.Tx0CheckBox_2.Value = true;
            app.Tx1CheckBox_2 = uicheckbox(app.Radar277GHzConfigurationPanel);
            app.Tx1CheckBox_2.Enable = 'off';
            app.Tx1CheckBox_2.Text = 'Tx1';
            app.Tx1CheckBox_2.Value = true;
            app.StartButton_2 = uibutton(app.Radar277GHzConfigurationPanel, 'push');
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.Radar277GHzConfigurationPanel, 'push');
            app.StopButton_2.Text = 'Stop';
            app.DCA1ConfigurationPanel = uipanel(app.RadarSetupTab);
            app.DCA1ConfigurationPanel.Title = '2. DCA 1 Configuration';
            app.SystemIPAddressEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.SystemIPAddressEditFieldLabel.Text = 'System IP Address';
            app.SystemIPAddressEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.SystemIPAddressEditField.Value = '192.168.33.30';
            app.DCAIPAddressEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.DCAIPAddressEditFieldLabel.Text = 'DCA IP Address';
            app.DCAIPAddressEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.DCAIPAddressEditField.Value = '192.168.33.180';
            app.ConfigPortEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.ConfigPortEditFieldLabel.Text = 'Config Port';
            app.ConfigPortEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.ConfigPortEditField.Value = '4096';
            app.DataPortEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.DataPortEditFieldLabel.Text = 'Data Port';
            app.DataPortEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.DataPortEditField.Value = '4098';
            app.PrepareDCA1Button = uibutton(app.DCA1ConfigurationPanel, 'push');
            app.PrepareDCA1Button.ButtonPushedFcn = createCallbackFcn(app, @PrepareDCA1ButtonPushed, true);
            app.PrepareDCA1Button.Text = 'Prepare DCA 1';
            app.DCA1PrepareLamp = uilamp(app.DCA1ConfigurationPanel);
            app.DCA2ConfigurationPanel = uipanel(app.RadarSetupTab);
            app.DCA2ConfigurationPanel.Title = '3. DCA 2 Configuration';
            app.SystemIPAddressEditField_2Label = uilabel(app.DCA2ConfigurationPanel);
            app.SystemIPAddressEditField_2Label.Text = 'System IP Address';
            app.SystemIPAddressEditField_2 = uieditfield(app.DCA2ConfigurationPanel, 'text');
            app.SystemIPAddressEditField_2.Value = '192.168.44.40';
            app.DCAIPAddressEditField_2Label = uilabel(app.DCA2ConfigurationPanel);
            app.DCAIPAddressEditField_2Label.Text = 'DCA IP Address';
            app.DCAIPAddressEditField_2 = uieditfield(app.DCA2ConfigurationPanel, 'text');
            app.DCAIPAddressEditField_2.Value = '192.168.44.180';
            app.ConfigPortEditField_2Label = uilabel(app.DCA2ConfigurationPanel);
            app.ConfigPortEditField_2Label.Text = 'Config Port';
            app.ConfigPortEditField_2 = uieditfield(app.DCA2ConfigurationPanel, 'text');
            app.ConfigPortEditField_2.Value = '4092';
            app.DataPortEditField_2Label = uilabel(app.DCA2ConfigurationPanel);
            app.DataPortEditField_2Label.Text = 'Data Port';
            app.DataPortEditField_2 = uieditfield(app.DCA2ConfigurationPanel, 'text');
            app.DataPortEditField_2.Value = '4094';
            app.PrepareDCA2Button = uibutton(app.DCA2ConfigurationPanel, 'push');
            app.PrepareDCA2Button.ButtonPushedFcn = createCallbackFcn(app, @PrepareDCA2ButtonPushed, true);
            app.PrepareDCA2Button.Text = 'Prepare DCA 2';
            app.DCA2PrepareLamp = uilamp(app.DCA2ConfigurationPanel);
            app.MCUSetupTab = uitab(app.TabGroup);
            app.MCUSetupTab.Title = 'MCU Setup';
            app.SerialConnectionsPanel_2 = uipanel(app.MCUSetupTab);
            app.SerialConnectionsPanel_2.Title = '1. Serial Connection(s)';
            app.ConnectMCUButton = uibutton(app.SerialConnectionsPanel_2, 'push');
            app.ConnectMCUButton.Text = 'Connect MCU';
            app.ScannerSetupTab = uitab(app.TabGroup);
            app.ScannerSetupTab.Title = 'Scanner Setup';
            app.SerialConnectionsPanel_3 = uipanel(app.ScannerSetupTab);
            app.SerialConnectionsPanel_3.Title = '1. Serial Connection(s)';
            app.ConnectScannerButton = uibutton(app.SerialConnectionsPanel_3, 'push');
            app.ConnectScannerButton.Text = 'Connect Scanner';
            app.DualRadarSARControllerLabel = uilabel(app.UIFigure);
            app.DualRadarSARControllerLabel.Text = 'Dual Radar SAR Controller';
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel = uilabel(app.UIFigure);
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel.Text = {'Author: Josiah W. Smith'; 'Advisor: Murat Torlak'};
            app.ConnectionsPanel = uipanel(app.UIFigure);
            app.ConnectionsPanel.Title = 'Connections';
            app.MCUConnectionLamp = uilamp(app.ConnectionsPanel);
            app.Radar2ConnectionLamp = uilamp(app.ConnectionsPanel);
            app.Radar1ConnectionLamp = uilamp(app.ConnectionsPanel);
            app.MCUConnectionLabel = uilabel(app.ConnectionsPanel);
            app.MCUConnectionLabel.Text = 'MCU Connection';
            app.Radar2ConnectionLabel = uilabel(app.ConnectionsPanel);
            app.Radar2ConnectionLabel.Text = 'Radar 2 Connection';
            app.Radar1ConnectionLabel = uilabel(app.ConnectionsPanel);
            app.Radar1ConnectionLabel.Text = 'Radar 1 Connection';
            app.ScannerConnectionLabel = uilabel(app.ConnectionsPanel);
            app.ScannerConnectionLabel.Text = 'Scanner Connection';
            app.ScannerConnectionLamp = uilamp(app.ConnectionsPanel);
            app.ConfigurationPanel = uipanel(app.UIFigure);
            app.ConfigurationPanel.Title = 'Configuration';
            app.MCUConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.Radar2ConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.Radar1ConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.MCUConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.MCUConfigurationLabel.Text = 'MCU Configuration';
            app.Radar2ConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.Radar2ConfigurationLabel.Text = 'Radar 2 Configuration';
            app.Radar1ConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.Radar1ConfigurationLabel.Text = 'Radar 1 Configuration';
            app.ScannerConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.ScannerConfigurationLabel.Text = 'Scanner Configuration';
            app.ScannerConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.MainTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dual_radar_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end